classdef judgeTS2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        JudgethetimeseriesButtonGroup   matlab.ui.container.ButtonGroup
        GoodButton                      matlab.ui.control.RadioButton
        RemovethestationButton          matlab.ui.control.RadioButton
        ErrorsinfirstyearsButton        matlab.ui.control.RadioButton
        ErrorsinlateryearsButton        matlab.ui.control.RadioButton
        IncreasingdecreasingtrendsButton  matlab.ui.control.RadioButton
        ErrorsinfirstandlateryrsButton  matlab.ui.control.RadioButton
        OthersButton                    matlab.ui.control.RadioButton
        ErrorsinthemiddleButton         matlab.ui.control.RadioButton
        ConvexconcavemixedButton        matlab.ui.control.RadioButton
        SaturatedButton                 matlab.ui.control.RadioButton
        Above1Button                    matlab.ui.control.RadioButton
        NextButton                      matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        k % depth
        n % station
        nstation
        ndepth
        depth 
        data
        stationflag
    end

    methods (Access = private)
        function updateplot(app,k,n,data)    
            depthfield = sprintf('depth%dcm', app.depth(k));
            varname = sprintf('Var%d',n);
            
            sm = data.(depthfield).sm(:,n);
            plot(app.UIAxes, sm.(varname));
            titlename = sprintf('station%d-depth%d', n, k);
            title(app.UIAxes, titlename);
    end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       cd("G:\Shared drives\Ryoko and Hilary\SoilMoistureSignature\SignatureAnalysis\3_codes_data_cleaners");
       site = ["OZ"]; % manual input % change here and 'UIFigureCloseRequest'
       obs = ["F"];   % manual input
       % Load the data
       wsname = sprintf('%s_%s_cleaned.mat', site, obs);
       load(fullfile("G:\Shared drives\Ryoko and Hilary\SoilMoistureSignature\SignatureAnalysis\4_data_after_qc", wsname));
        app.data = data;
        app.k = 1; %:size(depth,2)
        app.n = 1; %:nstation
        
        app.nstation = nstation;
        app.ndepth = size(depth,2);
        app.depth = depth;
        
        depthfield = sprintf('depth%dcm', depth(app.k));
        
        data.(depthfield).stationflag = zeros(app.nstation,1);
        app.stationflag = zeros(app.nstation,app.ndepth);
    
        titlename = sprintf('station%d-depth%d', app.n, app.k);
        title(app.UIAxes, titlename);
        xlabel(app.UIAxes,'Year');
        ylabel(app.UIAxes,'VWC(%)');
        
        varname = sprintf('Var%d',app.n);
        sm = data.(depthfield).sm(:,app.n);
        plot(app.UIAxes, sm.(varname));

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            if app.GoodButton.Value == 1
               app.stationflag(app.n,app.k) = 100;
            end
            
            if app.RemovethestationButton.Value == 1
                 app.stationflag(app.n,app.k) = 101;
            end
            
            if app.ErrorsinfirstyearsButton.Value == 1
                 app.stationflag(app.n,app.k) = 102;
            end
            
            if app.ErrorsinlateryearsButton.Value == 1
                 app.stationflag(app.n,app.k) = 103;
            end
            
            if app.ErrorsinfirstandlateryrsButton.Value == 1
                 app.stationflag(app.n,app.k) = 104;
            end
            
            if app.ErrorsinthemiddleButton.Value == 1
                 app.stationflag(app.n,app.k) = 105;
            end      
            
            if app.IncreasingdecreasingtrendsButton.Value == 1
                 app.stationflag(app.n,app.k) = 106;
            end      
            
            if app.ConvexconcavemixedButton.Value == 1
                 app.stationflag(app.n,app.k) = 107;
            end            

            if app.SaturatedButton.Value == 1
                 app.stationflag(app.n,app.k) = 108;
            end      
            
            if app.OthersButton.Value == 1
                 app.stationflag(app.n,app.k) = 109;
            end    
            
            if app.Above1Button.Value == 1
                 app.stationflag(app.n,app.k) = 110;
            end    
            
%                 if app.k == app.ndepth && app.n == app.nstation
%                 disp('finished!')
%                 closereq
%                 end       
                

                if app.n == app.nstation && app.k < app.ndepth
                    app.n = 1;
                    app.k = app.k+1;
                    updateplot(app, app.k, app.n, app.data)
                elseif app.k == app.ndepth && app.n == app.nstation
                    disp('finished!')
                    UIFigureCloseRequest(app, event)
                else
                    app.n = app.n+1;
                    updateplot(app, app.k, app.n, app.data)
                end

%                 if app.k <= app.ndepth && app.n <= app.nstation -1

%                 end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stationflag = app.stationflag;
            save('OZ_F_stationflag2.mat','stationflag'); % manual input
            delete(app)
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.n = app.n-1;
            updateplot(app, app.k, app.n, app.data)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.JudgethetimeseriesButtonGroup = uibuttongroup(app.UIFigure);
            app.JudgethetimeseriesButtonGroup.Title = 'Judge the time series';
            app.GoodButton = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.GoodButton.Text = 'Good';
            app.GoodButton.Value = true;
            app.RemovethestationButton = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.RemovethestationButton.Text = 'Remove the station ';
            app.ErrorsinfirstyearsButton = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.ErrorsinfirstyearsButton.Text = 'Errors in first years';
            app.ErrorsinlateryearsButton = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.ErrorsinlateryearsButton.Text = 'Errors in later years';
            app.IncreasingdecreasingtrendsButton = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.IncreasingdecreasingtrendsButton.Text = 'Increasing/decreasing trends ';
            app.ErrorsinfirstandlateryrsButton = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.ErrorsinfirstandlateryrsButton.Text = 'Errors in first and later yrs';
            app.OthersButton = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.OthersButton.Text = 'Others';
            app.ErrorsinthemiddleButton = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.ErrorsinthemiddleButton.Text = 'Errors in the middle';
            app.ConvexconcavemixedButton = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.ConvexconcavemixedButton.Text = 'Convex & concave mixed';
            app.SaturatedButton = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.SaturatedButton.Text = 'Saturated?';
            app.Above1Button = uiradiobutton(app.JudgethetimeseriesButtonGroup);
            app.Above1Button.Text = 'Above 1';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '→ Next';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = '← Back';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'VWC (%) ')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = judgeTS2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end